classdef NJApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout3                  matlab.ui.container.GridLayout
        total_words_edit_field       matlab.ui.control.NumericEditField
        CelkemvbrslovEditFieldLabel  matlab.ui.control.Label
        words_count                  matlab.ui.control.NumericEditField
        VybranchslovnajednouEditFieldLabel  matlab.ui.control.Label
        categories_list_box          matlab.ui.control.ListBox
        KategorieLabel               matlab.ui.control.Label
        ScorepanelPanel              matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        extra_label                  matlab.ui.control.Label
        score_label                  matlab.ui.control.Label
        ScoreLabel                   matlab.ui.control.Label
        OstatnslovajenzjednkategorieCheckBox  matlab.ui.control.CheckBox
        VechnyButton                 matlab.ui.control.Button
        ControlPanel                 matlab.ui.container.Panel
        GridLayout                   matlab.ui.container.GridLayout
        RESETButton                  matlab.ui.control.Button
        STARTButton                  matlab.ui.control.Button
        LOADEXCELButton              matlab.ui.control.Button
        word_label                   matlab.ui.control.Label
        pick_answer_box              matlab.ui.control.ListBox
        SlovoLabel                   matlab.ui.control.Label
    end

    
    properties (Access = public)
        words_table                 %table of all excel words
        only_selected_category = 1  %Determinate if selected words are from only one category
        selected_words = 5          %Randomly choosen words
        quiz_length = 0             %0 - all words from chosen category - other val - number of words
        selected_rows               %rows indexes from words table

        current_table               %reduced words table to suite settings
        current_czech_word          %currently picked czech word
        current_german_word         %currently picked german words
        current_index_array         %permuted list of indexes from current table

        score                       %current score
        total_words                 %total words from current_table
        total_words_selected        %total words redusec by quiz_length
        currently_selected_word = []%property for SF chart

        current_stch                %object of ST chart
    end
  
    methods (Access = public)
        
        function loadWords(app)
            if isdeployed
                file_name = uigetfile(pwd);
            else
                file_name = dir('*.xlsx').name;
            end
            app.words_table = readtable(file_name);
        end
        function printScore(app)
            app.score_label.Text = [num2str(app.score), ' / ', num2str(app.total_words_selected)];
        end
        function addWordsToTheList(app, word_list)
            shuffled_word_list = word_list(randperm(length(word_list)));
            for i = 1:length(word_list)
                app.addWordToTheList(shuffled_word_list{i});
            end
        end
        function addWordToTheList(app, word)
            if isempty(app.pick_answer_box.Items)
                app.pick_answer_box.Items{1} = word;
            else
                app.pick_answer_box.Items{end+1} = word;
            end
        end
        function addCategoriesToTheList(app, categories)
            for i = 1:length(categories)
                app.addCategoryToTheList(categories{i})
            end
        end
        function addCategoryToTheList(app, category)
            if isempty(app.categories_list_box.Items)
                app.categories_list_box.Items{1} = category;
            else
                app.categories_list_box.Items{end+1} = category;
            end
        end
        function clearList(app)
            app.pick_answer_box.Items = {};
        end
        function clearCategories(app)
            app.categories_list_box.Items = {};
        end
        function fillCategories(app)
            app.clearCategories();
            app.addCategoriesToTheList(unique(app.words_table.('Kategorie')));
        end
        function category = getSelectedCategory(app)
            category = app.categories_list_box.Value;
        end
        function getSelectedWords(app)
            app.selected_words = app.words_count.Value;
        end
        function selectCategory(app, item_number)
            if item_number > numel(app.categories_list_box.Items)
                error('Hight item number than maximum')
            end
            app.categories_list_box.Value = app.categories_list_box.Items{item_number};
        end
        function setQuizLength(app)
            app.quiz_length = app.total_words_edit_field.Value;
        end
        function setSelectedRows(app)
            if ~isempty(app.getSelectedCategory)
                cell_of_categories = app.getSelectedCategory;
                app.selected_rows = zeros(size(app.words_table,1),1); 
                for i = 1:size(cell_of_categories,2)
                    app.selected_rows = app.selected_rows + strcmp(app.words_table.('Kategorie'), cell_of_categories{i});
                end
            else
                app.selected_rows = 1:size(app.words_table,1);
            end
        end
        function setCurrentTable(app)
            app.current_table = app.words_table(app.selected_rows==1, :);
        end
        function setTotalWords(app)
            app.total_words = size(app.current_table,1);
            if app.quiz_length == 0
                app.total_words_selected = app.total_words;
            else
                app.total_words_selected = app.quiz_length;
            end
        end
        function addScore(app)
            app.score = app.score+1;
        end
        function generateNewIndexArray(app)
            index_array = randperm(app.total_words_selected);
            app.current_index_array = index_array(1:app.total_words_selected);
        end
        function index_array = getIndexArray(app)
            index_array = app.current_index_array;
        end
        function setRandomWords(app)
            if app.only_selected_category
                random_words = app.current_table.('Nemecke_slovo')(randperm(size(app.current_table,1),app.selected_words));
            else
                random_words = app.words_table.('Nemecke_slovo')(randperm(size(app.words_table,1),app.selected_words));
            end
            app.word_label.Text = app.current_czech_word;
            app.clearList()
            app.addWordsToTheList(unique([random_words; {app.current_german_word}]))
        end
        function resetQuiz(app)
            app.total_words_selected = 0;
            app.extra_label.Text = '';
            app.clearList();
            app.score = 0;
            app.printScore();
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Quizz settings
            app.selected_words = app.words_count.Value;
            
            %Load words from txts
            app.loadWords();
            app.fillCategories();
            app.selectCategory(1)
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            if isempty(app.current_stch)
                app.current_stch = quizFlow(app=app);
            else
                delete(app.current_stch)    
                app.current_stch = quizFlow(app=app);
            end
        end

        % Value changed function: pick_answer_box
        function pick_answer_boxValueChanged(app, event)
            app.currently_selected_word = app.pick_answer_box.Value;
            app.current_stch.answer_picked;
            app.current_stch.step;
            app.current_stch.step;
        end

        % Callback function
        function VechnyCheckBoxValueChanged(app, event)
            disp('1')
        end

        % Button pushed function: VechnyButton
        function VechnyButtonPushed(app, event)
            app.categories_list_box.Value = {};
        end

        % Button pushed function: LOADEXCELButton
        function LOADEXCELButtonPushed(app, event)
            app.loadWords()
        end

        % Value changed function: OstatnslovajenzjednkategorieCheckBox
        function OstatnslovajenzjednkategorieCheckBoxValueChanged(app, event)
            app.only_selected_category = app.OstatnslovajenzjednkategorieCheckBox.Value;
        end

        % Value changed function: words_count
        function words_countValueChanged(app, event)
            app.selected_words = app.words_count.Value;
        end

        % Callback function
        function FORCESTOPALLQUIZButtonValueChanged(app, event)
            value = app.FORCESTOPALLQUIZButton.Value;
            if value == 1
                app.FORCESTOPALLQUIZButton.BackgroundColor = 'red';
            else
                app.FORCESTOPALLQUIZButton.BackgroundColor = '#eee';
            end
            app.forced_quit = value;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
             if ~isempty(app.current_stch)
                delete(app.current_stch)
            end
            delete(app)
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            app.resetQuiz()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout3 = uigridlayout(app.UIFigure);
            app.SlovoLabel = uilabel(app.GridLayout3);
            app.SlovoLabel.Text = 'Slovo:';
            app.pick_answer_box = uilistbox(app.GridLayout3);
            app.pick_answer_box.ValueChangedFcn = createCallbackFcn(app, @pick_answer_boxValueChanged, true);
            app.word_label = uilabel(app.GridLayout3);
            app.word_label.Text = 'Vybrané slovo:';
            app.ControlPanel = uipanel(app.GridLayout3);
            app.ControlPanel.Title = 'Control';
            app.GridLayout = uigridlayout(app.ControlPanel);
            app.LOADEXCELButton = uibutton(app.GridLayout, 'push');
            app.LOADEXCELButton.ButtonPushedFcn = createCallbackFcn(app, @LOADEXCELButtonPushed, true);
            app.LOADEXCELButton.Text = 'LOAD EXCEL';
            app.STARTButton = uibutton(app.GridLayout, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.RESETButton = uibutton(app.GridLayout, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.VechnyButton = uibutton(app.GridLayout3, 'push');
            app.VechnyButton.ButtonPushedFcn = createCallbackFcn(app, @VechnyButtonPushed, true);
            app.VechnyButton.Text = 'Všechny';
            app.OstatnslovajenzjednkategorieCheckBox = uicheckbox(app.GridLayout3);
            app.OstatnslovajenzjednkategorieCheckBox.ValueChangedFcn = createCallbackFcn(app, @OstatnslovajenzjednkategorieCheckBoxValueChanged, true);
            app.OstatnslovajenzjednkategorieCheckBox.Text = 'Ostatní slova jen z jedné kategorie';
            app.ScorepanelPanel = uipanel(app.GridLayout3);
            app.ScorepanelPanel.Title = 'Score panel';
            app.GridLayout2 = uigridlayout(app.ScorepanelPanel);
            app.ScoreLabel = uilabel(app.GridLayout2);
            app.ScoreLabel.Text = 'Score:';
            app.score_label = uilabel(app.GridLayout2);
            app.score_label.Text = '0';
            app.extra_label = uilabel(app.GridLayout2);
            app.extra_label.Text = '';
            app.KategorieLabel = uilabel(app.GridLayout3);
            app.KategorieLabel.Text = 'Kategorie:';
            app.categories_list_box = uilistbox(app.GridLayout3);
            app.categories_list_box.Multiselect = 'on';
            app.categories_list_box.Value = {'Item 1'};
            app.VybranchslovnajednouEditFieldLabel = uilabel(app.GridLayout3);
            app.VybranchslovnajednouEditFieldLabel.Text = 'Vybraných slov najednou:';
            app.words_count = uieditfield(app.GridLayout3, 'numeric');
            app.words_count.ValueChangedFcn = createCallbackFcn(app, @words_countValueChanged, true);
            app.words_count.Value = 5;
            app.CelkemvbrslovEditFieldLabel = uilabel(app.GridLayout3);
            app.CelkemvbrslovEditFieldLabel.Text = 'Celkem výběr slov:';
            app.total_words_edit_field = uieditfield(app.GridLayout3, 'numeric');
            app.total_words_edit_field.Limits = [0 Inf];
            app.total_words_edit_field.RoundFractionalValues = 'on';
            app.total_words_edit_field.ValueDisplayFormat = '%d';
            app.total_words_edit_field.Tooltip = {'Musí být přirozené číslo'; '0 pro všechny z kategorie'};
            app.total_words_edit_field.Value = 10;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NJApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end